classdef ToneVocoder < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        LeftPanel            matlab.ui.container.Panel
        SETTINGButton        matlab.ui.control.Button
        FCUTSlider           matlab.ui.control.Slider
        FCUTSliderLabel      matlab.ui.control.Label
        BANDSlider           matlab.ui.control.Slider
        BANDSliderLabel      matlab.ui.control.Label
        ADDSSNButtonGroup    matlab.ui.container.ButtonGroup
        NoButton             matlab.ui.control.RadioButton
        YesButton            matlab.ui.control.RadioButton
        BPFMODEButtonGroup   matlab.ui.container.ButtonGroup
        Mode1Button          matlab.ui.control.RadioButton
        Mode0Button          matlab.ui.control.RadioButton
        N_LPFEditField       matlab.ui.control.NumericEditField
        N_LPFEditFieldLabel  matlab.ui.control.Label
        N_BPFEditField       matlab.ui.control.NumericEditField
        N_BPFEditFieldLabel  matlab.ui.control.Label
        RightPanel           matlab.ui.container.Panel
        SoundButton          matlab.ui.control.Button
        GenerateButton       matlab.ui.control.Button
        OpenButton           matlab.ui.control.Button
        UIAxes2              matlab.ui.control.UIAxes
        UIAxes               matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = private)
        SSN = 'N'; % Add SNR 'Y' or not 'N'
        Mode = 0; % Equal frequency: 0 | Average cochlea length: 1
        N_sub = 4; % Number of segments
        N_BPF = 4; % BPF order
        Cf = 50; % Cut-off frequence of LPF
        N_LPF = 4; % LPF order

        sound_read % Description
        t % Description
        y % Description
        fs % Description
        w0 % Description
        ak % Description

        isOpen = 0; % Description
        source % Description
    end

    methods (Access = private)


        function results = main (app)
            % Tone Vocoder

            mkdir Output\

            % Add SNR
            if app.SSN == 'Y'
                app.sound_read = getSSN(app, app.sound_read, app.fs);
            end

            % Passband Mode
            % Mode 0 Equal frequency
            if app.Mode == 0

                Nmax = app.N_sub;
                fmin=200;
                fmax=7000;

                % Generate an array of buffer filter coefficients
                b = zeros(Nmax, 2 * app.N_BPF + 1);
                a = zeros(Nmax, 2 * app.N_BPF + 1);
                gap = (fmax - fmin) / Nmax;
                for j = 1 : Nmax
                    [b(j, :), a(j, :)] = butter(app.N_BPF, [fmin + (j-1)*gap fmin + j*gap] / app.fs * 2);
                end

                % Generate sub-bands
                cuted = zeros(Nmax, length(app.sound_read));
                for j = 1 : Nmax
                    cuted(j, :) = filter(b(j, :), a(j, :), app.sound_read);
                end

                % Generate the Envelope
                envelopes = Envelope(app, cuted(:, :), app.fs, app.N_LPF, app.Cf);

                % Carrier loaded
                loaded = zeros(Nmax, length(app.sound_read));
                gap = (fmax - fmin) / Nmax;
                for j = 1 : Nmax
                    for k = 1 : length(app.sound_read)
                        loaded(j, k) = envelopes(j, k) .* cos(2*pi * (fmin + (j-1/2)*gap) * (k-1) / app.fs);
                    end
                end

                % Synthesis and normalization
                output = zeros(1, length(app.sound_read));
                for j = 1 : Nmax
                    output = output + loaded(j, :);
                end

                %                 % Display the output
                %                 sound(output, app.fs);
                %                 figure;
                %                 plot(app.t, output);

                app.ak = fftshift(abs(fft(output)));
                app.w0 = linspace(-7000, 7000, length(abs(fft(output))));

                if app.SSN == 'Y'
                    audiowrite(['./Output/EqualFrequency_WithSSN_',num2str(app.N_sub),'Bands_',num2str(app.Cf),'Hz.wav'], output, app.fs);
                end
                if app.SSN == 'N'
                    audiowrite(['./Output/EqualFrequency_',num2str(app.N_sub),'Bands_',num2str(app.Cf),'Hz.wav'], output, app.fs);
                end
                app.y = output;
                results = [output, app.fs, app.t];

                % Mode 1 Average cochlea length
            elseif app.Mode == 1

                dmin = (50 * log(1827 / 827)) / (3 * log(10));
                dmax = (50 * log(35827 / 827)) / (3 * log(10));
                Nmax = app.N_sub;

                % Generate an array of buffer filter coefficients
                b = zeros(Nmax, 2 * app.N_BPF + 1);
                a = zeros(Nmax, 2 * app.N_BPF + 1);
                gap = (dmax - dmin) / Nmax;
                for j = 1 : Nmax
                    [b(j, :), a(j, :)] = butter(app.N_BPF, [alter(app, dmin + (j-1)*gap) alter(app, dmin + j*gap)] / app.fs * 2);
                end

                % Generate sub-bands
                cuted = zeros(Nmax, length(app.sound_read));
                for j = 1 : Nmax
                    cuted(j, :) = filter(b(j, :), a(j, :), app.sound_read);
                end

                % Generate the Envelope
                envelopes = Envelope(app, cuted(:, :), app.fs, app.N_LPF, app.Cf);

                % Carrier loaded
                loaded = zeros(Nmax, length(app.sound_read));
                gap = (dmax - dmin) / Nmax;
                for j = 1 : Nmax
                    for k = 1 : length(app.sound_read)
                        loaded(j, k) = envelopes(j, k) .* cos(2*pi * alter(app, dmin + (j-1/2)*gap) * (k-1) / app.fs);
                    end
                end

                % Synthesis and normalization
                output = zeros(1, length(app.sound_read));
                for j = 1 : Nmax
                    output = output + loaded(j, :);
                end

                %                 % Display the outputs
                %                 pause(5);
                %                 sound(output, app.fs);
                %                 figure;
                %                 plot(app.t, output);

                app.ak = fftshift(abs(fft(output)));
                app.w0 = linspace(-7000, 7000, length(abs(fft(output))));

                if app.SSN == 'Y'
                    audiowrite(['./Output/Cochlea_WithSSN_',num2str(app.N_sub),'Bands_',num2str(app.Cf),'Hz.wav'], output, app.fs);
                end
                if app.SSN == 'N'
                    audiowrite(['./Output/Cochlea_',num2str(app.N_sub),'Bands_',num2str(app.Cf),'Hz.wav'], output, app.fs);
                end
                app.y = output;
                results = [output, app.fs, app.t];
            end

        end

        % Functions

        % Generate SSN
        function Out = getSSN(~, sound_read, fs)
            nfft = 512;
            noverlap = nfft / 2;
            Windows = hamming(nfft);
            % Returns the power spectral density
            [Pxx,w] = pwelch(repmat(sound_read, 1, 10), Windows, noverlap, nfft, fs);
            % Generate filter
            b = fir2(3000, w/(fs / 2), sqrt(Pxx / max(Pxx)));
            % Generate white noise
            N = length(sound_read);
            noise = 1 - 2 * rand(1, N + length(b) - 1);
            % Generate SSN
            ssn = filter(b, 1, noise);
            ssn = ssn(length(b) : end);
            % Adjust the energy of SSN
            ssn = ssn / norm(ssn) * norm(sound_read) * 10 ^ (1 / 4);
            % Addition sound_read with ssn
            Out = sound_read + ssn';
            % Normalize out to sound_read
            Out = Out / norm(Out) * norm(sound_read);
        end

        % Batch Extract Envelopes
        function E = Envelope(~, Y, fs, N1, cf)
            [b, a] = butter(N1, cf / (fs / 2), "low");
            sizeY = size(Y);
            m1 = sizeY(1);
            m2 = sizeY(2);
            Y = abs(Y);
            E = zeros(m1, m2);
            for n = 1 : m1
                E(n, :) = filter(b, a, Y(n, :));
            end
        end

        % f-d Alter
        function f = alter(~, d)
            f=165.4*(10^(0.06*d)-1);
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Selection changed function: BPFMODEButtonGroup
        function BPFMODEButtonGroupSelectionChanged(app, event)
            selectedButton = app.BPFMODEButtonGroup.SelectedObject;
            if selectedButton == app.Mode0Button
                app.Mode = 0;
            elseif selectedButton == app.Mode1Button
                app.Mode = 1;
            end
        end

        % Selection changed function: ADDSSNButtonGroup
        function ADDSSNButtonGroupSelectionChanged(app, event)
            selectedButton = app.ADDSSNButtonGroup.SelectedObject;
            if selectedButton == app.YesButton
                app.SSN = 'Y';
            elseif selectedButton == app.NoButton
                app.SSN = 'N';
            end
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            if app.isOpen == 1
                app.sound_read = app.source;
                app.main;
                plot(app.UIAxes, app.t, app.y);
                plot(app.UIAxes2, app.w0, app.ak);
            else
                msgbox('Please open a sound file ~')
            end
        end

        % Value changed function: BANDSlider
        function BANDSliderValueChanged(app, event)
            value = app.BANDSlider.Value;
            app.N_sub = ceil(value);
        end

        % Value changed function: FCUTSlider
        function FCUTSliderValueChanged(app, event)
            value = app.FCUTSlider.Value;
            app.Cf = ceil(value);
        end

        % Value changed function: N_BPFEditField
        function N_BPFEditFieldValueChanged(app, event)
            value = app.N_BPFEditField.Value;
            app.N_BPF = value;
        end

        % Value changed function: N_LPFEditField
        function N_LPFEditFieldValueChanged(app, event)
            value = app.N_LPFEditField.Value;
            app.N_LPF = value;
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            [filename,pathname] = uigetfile({'*.wav'},'请打开音频捏');
            if pathname ~= 0
            str = [pathname filename];
            app.isOpen = 1;
            [app.source, app.fs] = audioread(str);
            app.sound_read = app.source;
            app.t = (0 : (length(app.sound_read) - 1)) / app.fs;
            app.y = app.sound_read';
            plot(app.UIAxes, app.t, app.y);
            app.ak = fftshift(abs(fft(app.y)));
            app.w0 = linspace(-7000, 7000, length(abs(fft(app.y))));
            plot(app.UIAxes2, app.w0, app.ak);
            end
        end

        % Button pushed function: SoundButton
        function SoundButtonPushed(app, event)

            if app.isOpen == 1
                sound(app.y, app.fs);
            else
                msgbox('Please open a sound file ~')
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {410, 410};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {199, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.N_BPFEditFieldLabel = uilabel(app.LeftPanel);
            app.N_BPFEditFieldLabel.Text = 'N_BPF';
            app.N_BPFEditField = uieditfield(app.LeftPanel, 'numeric');
            app.N_BPFEditField.ValueChangedFcn = createCallbackFcn(app, @N_BPFEditFieldValueChanged, true);
            app.N_BPFEditField.Value = 4;
            app.N_LPFEditFieldLabel = uilabel(app.LeftPanel);
            app.N_LPFEditFieldLabel.Text = 'N_LPF';
            app.N_LPFEditField = uieditfield(app.LeftPanel, 'numeric');
            app.N_LPFEditField.ValueChangedFcn = createCallbackFcn(app, @N_LPFEditFieldValueChanged, true);
            app.N_LPFEditField.Value = 4;
            app.BPFMODEButtonGroup = uibuttongroup(app.LeftPanel);
            app.BPFMODEButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BPFMODEButtonGroupSelectionChanged, true);
            app.BPFMODEButtonGroup.Title = 'BPF MODE';
            app.Mode0Button = uiradiobutton(app.BPFMODEButtonGroup);
            app.Mode0Button.Text = 'Mode 0';
            app.Mode0Button.Value = true;
            app.Mode1Button = uiradiobutton(app.BPFMODEButtonGroup);
            app.Mode1Button.Text = 'Mode 1';
            app.ADDSSNButtonGroup = uibuttongroup(app.LeftPanel);
            app.ADDSSNButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ADDSSNButtonGroupSelectionChanged, true);
            app.ADDSSNButtonGroup.Title = 'ADD SSN';
            app.YesButton = uiradiobutton(app.ADDSSNButtonGroup);
            app.YesButton.Text = 'Yes';
            app.NoButton = uiradiobutton(app.ADDSSNButtonGroup);
            app.NoButton.Text = 'No';
            app.NoButton.Value = true;
            app.BANDSliderLabel = uilabel(app.LeftPanel);
            app.BANDSliderLabel.Text = 'BAND';
            app.BANDSlider = uislider(app.LeftPanel);
            app.BANDSlider.Limits = [0 150];
            app.BANDSlider.ValueChangedFcn = createCallbackFcn(app, @BANDSliderValueChanged, true);
            app.BANDSlider.Value = 4;
            app.FCUTSliderLabel = uilabel(app.LeftPanel);
            app.FCUTSliderLabel.Text = 'FCUT';
            app.FCUTSlider = uislider(app.LeftPanel);
            app.FCUTSlider.Limits = [0 200];
            app.FCUTSlider.ValueChangedFcn = createCallbackFcn(app, @FCUTSliderValueChanged, true);
            app.FCUTSlider.Value = 50;
            app.SETTINGButton = uibutton(app.LeftPanel, 'push');
            app.SETTINGButton.Text = 'SETTING';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Sound Wave')
            zlabel(app.UIAxes, ' ')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Spectrum')
            app.OpenButton = uibutton(app.RightPanel, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.GenerateButton = uibutton(app.RightPanel, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.SoundButton = uibutton(app.RightPanel, 'push');
            app.SoundButton.ButtonPushedFcn = createCallbackFcn(app, @SoundButtonPushed, true);
            app.SoundButton.Text = 'Sound';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ToneVocoder

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end